classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Label2      matlab.ui.control.Label
        Label       matlab.ui.control.Label
        Slider      matlab.ui.control.Slider
        Button      matlab.ui.control.Button
        DropDown_3  matlab.ui.control.DropDown
        DropDown_2  matlab.ui.control.DropDown
        DropDown    matlab.ui.control.DropDown
        ListBox     matlab.ui.control.ListBox
        UIAxes      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define the URL
            link = 'https://data.transportation.gov/resource/8ect-6jqj.json?location=';
            
url = [link,app.DropDown_3.Value];

% Specify the app token if needed
appToken = 'VNEI5hNy8NgCnI0lmzVtmlCE1';
options = weboptions('KeyName', 'app_token', 'KeyValue', appToken);

% Retrieve data from the URL
data = webread(url, options);

% Extract unique locations
locations = unique({data.location});

% Create a MATLAB app and populate the dropdown
app.DropDown.Items = locations;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ListBox = uilistbox(app.UIFigure);
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_3.Items = {'i-80', 'us-101', 'peachtree', 'lankershim'};
            app.DropDown_3.Value = 'i-80';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Slider = uislider(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end